classdef ApplyWatershedAndVornoiSegmentCells2DWithCurvatureIslands < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DefinePointsofInterestPanel     matlab.ui.container.Panel
        SelectPointsofInterestButton    matlab.ui.control.Button
        ofpointsEditField               matlab.ui.control.NumericEditField
        ofpointsEditFieldLabel          matlab.ui.control.Label
        ExtractStatsPerCellandBatchPanel  matlab.ui.container.Panel
        LoadParamsButton                matlab.ui.control.Button
        SaveParamsButton                matlab.ui.control.Button
        StatsFromVoronoiButton          matlab.ui.control.Button
        StatsFromWatershedButton        matlab.ui.control.Button
        BatchusingsamesettingsButton    matlab.ui.control.Button
        ComputeBoundaryCurvaturePanel   matlab.ui.container.Panel
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        SmoothBoundaryDropDown          matlab.ui.control.DropDown
        SmoothBoundaryDropDownLabel     matlab.ui.control.Label
        CalculateCurvatureButton        matlab.ui.control.Button
        Open0orClosed1bdryEditField     matlab.ui.control.NumericEditField
        Open0orClosed1bdryEditFieldLabel  matlab.ui.control.Label
        MinDistanceInterpSlider         matlab.ui.control.Slider
        MinDistanceInterpSliderLabel    matlab.ui.control.Label
        CurvatureThresholdSlider        matlab.ui.control.Slider
        CurvatureThresholdSliderLabel   matlab.ui.control.Label
        BoundaryPointsUsedForTangentSlider  matlab.ui.control.Slider
        BoundaryPointsUsedForTangentSliderLabel  matlab.ui.control.Label
        BoundaryPointsUsedForCurvatureSlider  matlab.ui.control.Slider
        BoundaryPointsUsedForCurvatureSliderLabel  matlab.ui.control.Label
        DVoronoiCombinedToNucleiMaskPanel  matlab.ui.container.Panel
        Final2DVoronoiSegmentationButton  matlab.ui.control.Button
        AreaLBEditField_2               matlab.ui.control.NumericEditField
        AreaLBEditField_2Label          matlab.ui.control.Label
        AreaUBEditField_2               matlab.ui.control.NumericEditField
        AreaUBEditField_2Label          matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Show2DVoronoiCellDecompositionFromNuclearLocationsButton  matlab.ui.control.Button
        DefineOverallNucleiMaskSlider   matlab.ui.control.Slider
        DefineOverallNucleiMaskLabel    matlab.ui.control.Label
        ApplyWatershedAndDefineCellAreasPanel  matlab.ui.container.Panel
        FilterSigmaSlider               matlab.ui.control.Slider
        FilterSigmaSliderLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FinalSegmentationButton         matlab.ui.control.Button
        ChannelForAlternativeConstrainBassinsDropDown  matlab.ui.control.DropDown
        ChannelForAlternativeConstrainBassinsDropDownLabel  matlab.ui.control.Label
        AreaLBEditField                 matlab.ui.control.NumericEditField
        AreaLBEditFieldLabel            matlab.ui.control.Label
        AreaUBEditField                 matlab.ui.control.NumericEditField
        AreaUBEditFieldLabel            matlab.ui.control.Label
        DisplayResultsDropDown          matlab.ui.control.DropDown
        DisplayResultsDropDownLabel     matlab.ui.control.Label
        ApplyWatershedButton            matlab.ui.control.Button
        DefineOverallCellMaskPanel      matlab.ui.container.Panel
        LBCellAreaSlider                matlab.ui.control.Slider
        LBCellAreaSliderLabel           matlab.ui.control.Label
        GenerateOverallCellsMaskButton  matlab.ui.control.Button
        ImageforoverallcellshapebinaryDropDown  matlab.ui.control.DropDown
        ImageforoverallcellshapebinaryDropDownLabel  matlab.ui.control.Label
        BinariseImage2Slider            matlab.ui.control.Slider
        BinariseImage2SliderLabel       matlab.ui.control.Label
        DefineTheCellCenterPositionsPanel  matlab.ui.container.Panel
        MinimumPeakDistanceEditField    matlab.ui.control.NumericEditField
        MinimumPeakDistanceEditFieldLabel  matlab.ui.control.Label
        DefineNucleiSmoothingRadiusSlider  matlab.ui.control.Slider
        DefineNucleiSmoothingRadiusSliderLabel  matlab.ui.control.Label
        SelectChannelToDefineCellNucleiCentersDropDown  matlab.ui.control.DropDown
        SelectChannelToDefineCellNucleiCentersDropDownLabel  matlab.ui.control.Label
        CellCentersDetectionSlider      matlab.ui.control.Slider
        CellCentersDetectionSliderLabel  matlab.ui.control.Label
        LoadOMEImageDataButton          matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fname
        im % Description
        im1
        im2
        im3
        MinPeakDist
        filtnuc
        choice1
        choice2
        choice3
        choice4
        thresh1
        thresh2
        thresh3
        out
        bw2
        Lb
        pix
        I_eq_c
        I_mod
        areaUB
        areaLB
        filtAlternative
        filtChoice
        filtSig
        posNeg
        bwBdry2
        bwBdryFinal
        bw3
        mask1
        border
        voronoi
        mask2
        ThreshNucleiMask
        maskcombine
        Lb2
        areaUB2
        areaLB2
        boundaryPoint
        curvatureThresh
        bp_tangent
        interpdmin
        loopclose
        curveX
        curveY
        curveRaw
        curveThresh
        LBcellArea
        MovAveFilt
        bw2b
        sigmaBdry
        ptsint
        numPtsInt
        distToPtsInt
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOMEImageDataButton
        function LoadOMEImageDataButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.czi;*.nd2;*.oif;*.lif','OME files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                app.fname=fname;
                [im,specs]=readOMEAlternative(app.fname);
                app.im=double(im);
                %updateimage(app, fname);
                meta=GetOMEData(app.fname);
                app.pix=meta.ScaleX;
                app.AreaUBEditField.Value=500000;
                app.AreaLBEditField.Value=50;
                app.areaUB2=10000;
                app.areaLB2=50;
                app.areaUB=10000;
                app.areaLB=50;
                app.MinPeakDist=30;
                app.filtSig=10;
                app.filtChoice='g';
                app.posNeg='p';
                app.MovAveFilt='raw';
                app.boundaryPoint=10;
                app.curvatureThresh=0.1;
                app.bp_tangent=10;
                app.interpdmin=0.1;
                app.loopclose=1;
                app.curveX=[];
                app.curveY=[];
                app.curveRaw=[];
                app.curveThresh=[];
                app.im1=[];
                app.im2=[];
                app.im3=[];
                app.bw2=[];
                app.bw2b=[];
                app.bw3=[];
                app.mask1=[];
                app.mask2=[];
                app.ptsint=[];
                app.numPtsInt=[];
                app.distToPtsInt=[];
                app.voronoi=[];
                app.ThreshNucleiMask=[];
                app.Lb2=[];
                compim(:,:,1)=imadjust(normalisation(app.im(:,:,1)));
                compim(:,:,2)=imadjust(normalisation(app.im(:,:,2)));
                if size(app.im,3)>2
                compim(:,:,3)=imadjust(normalisation(app.im(:,:,3)));
                else
                    compim(:,:,3)=zeros(size(app.im,1),size(app.im,1));
                end
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                imagesc(app.UIAxes,compim);
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                xlim(app.UIAxes,[1 size(app.im,2)])
                ylim(app.UIAxes,[1 size(app.im,1)])
            end
        end

        % Value changed function: 
        % SelectChannelToDefineCellNucleiCentersDropDown
        function SelectChannelToDefineCellNucleiCentersDropDownValueChanged(app, event)
            value = app.SelectChannelToDefineCellNucleiCentersDropDown.Value;
            app.choice1=value;
            if strcmp(value,'Ch=1')
                app.im1=squeeze(app.im(:,:,1));
            elseif strcmp(value,'Ch=2')
                app.im1=squeeze(app.im(:,:,2));
            elseif strcmp(value,'Ch=3')
                app.im1=squeeze(app.im(:,:,3));
            elseif strcmp(value,'Ch=4')
                app.im1=squeeze(app.im(:,:,4));
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.im1));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changing function: DefineNucleiSmoothingRadiusSlider
        function DefineNucleiSmoothingRadiusSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.filtnuc,h2]=smoothSeries2(app.im1,changingValue,'g','p');
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.filtnuc));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: DefineNucleiSmoothingRadiusSlider
        function DefineNucleiSmoothingRadiusSliderValueChanged(app, event)
            value = app.DefineNucleiSmoothingRadiusSlider.Value;
            app.thresh1=value;
        end

        % Value changed function: MinimumPeakDistanceEditField
        function MinimumPeakDistanceEditFieldValueChanged(app, event)
            value = app.MinimumPeakDistanceEditField.Value;
            app.MinPeakDist=value;
            [app.filtnuc,h2]=smoothSeries2(app.im1,app.thresh1,'g','p');
            norm1=app.filtnuc/max(app.filtnuc(:));
            app.out=pkfnd(norm1,app.thresh2,app.MinPeakDist);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,norm1);
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.out(:,1),app.out(:,2),'ro')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changing function: CellCentersDetectionSlider
        function CellCentersDetectionSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.filtnuc,h2]=smoothSeries2(app.im1,app.thresh1,'g','p');
            norm1=app.filtnuc/max(app.filtnuc(:));
            app.out=pkfnd(norm1,changingValue/10,app.MinPeakDist);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,norm1);
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.out(:,1),app.out(:,2),'ro')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: CellCentersDetectionSlider
        function CellCentersDetectionSliderValueChanged(app, event)
            value = app.CellCentersDetectionSlider.Value;
            app.thresh2=value/10;
        end

        % Value changed function: 
        % ImageforoverallcellshapebinaryDropDown
        function ImageforoverallcellshapebinaryDropDownValueChanged(app, event)
            value = app.ImageforoverallcellshapebinaryDropDown.Value;
            app.choice2=value;
            if strcmp(value,'Ch=1')
                app.im2=squeeze(app.im(:,:,1));
            elseif strcmp(value,'Ch=2')
                app.im2=squeeze(app.im(:,:,2));
            elseif strcmp(value,'Ch=3')
                app.im2=squeeze(app.im(:,:,3));
            elseif strcmp(value,'Ch=4')
                app.im2=squeeze(app.im(:,:,4));
            elseif strcmp(value,'max. int proj. all Channels')
                app.im2=squeeze(max(app.im,[],3));
            elseif strcmp(value,'<I(Ch)>')
                app.im2=squeeze(mean(app.im,3));
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.im2));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changing function: BinariseImage2Slider
        function BinariseImage2SliderValueChanging(app, event)
            changingValue = event.Value;
            norm2=app.im2/max(app.im2(:));
            bw2=norm2>changingValue/10;
            % filter binary
            cc = bwconncomp(bw2);
            stats = regionprops(cc, 'Area');
            idx = find([stats.Area] >((app.MinPeakDist)^2));
            app.bw2 = ismember(labelmatrix(cc), idx);
            app.bw2=bwmorph(app.bw2,'clean');
            app.bw2=imfill(app.bw2,'holes');
            %app.bw2=bwmorph(app.bw2,'open');
            app.bw2=bwmorph(app.bw2,'close');
            se=strel('disk',5);
            app.bw2=imclose(app.bw2,se);;
            normim1=uint8(255*norm2);
            B=labeloverlay(normim1,app.bw2);
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,B);
        end

        % Value changed function: BinariseImage2Slider
        function BinariseImage2SliderValueChanged(app, event)
            value = app.BinariseImage2Slider.Value;
            app.thresh3=value/10;
        end

        % Value changing function: LBCellAreaSlider
        function LBCellAreaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBcellArea=changingValue;
            stats=regionprops(app.bw2,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.bw2);
            BW2 = ismember(labelmatrix(cc),idx);
            normim1=app.im2/max(app.im2(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,BW2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(BW2,2)])
            ylim(app.UIAxes,[1 size(BW2,1)])
        end

        % Value changed function: LBCellAreaSlider
        function LBCellAreaSliderValueChanged(app, event)
            value = app.LBCellAreaSlider.Value;
            app.LBcellArea=value;
        end

        % Button pushed function: GenerateOverallCellsMaskButton
        function GenerateOverallCellsMaskButtonPushed(app, event)
            stats=regionprops(app.bw2,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.bw2);
            BW2 = ismember(labelmatrix(cc),idx);
            app.bw2=BW2>0;
            app.bw2=bwmorph(app.bw2,'clean');
            app.bw2=imfill(app.bw2,'holes');
            %app.bw2=bwmorph(app.bw2,'open');
            app.bw2=bwmorph(app.bw2,'close');
            app.bw2=bwmorph(app.bw2,'clean');
            normim1=app.im2/max(app.im2(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,BW2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(BW2,2)])
            ylim(app.UIAxes,[1 size(BW2,1)])
            bw2=app.bw2;
            save([app.fname(1:end-4) 'masks.mat'],'bw2')
        end

        % Value changed function: 
        % ChannelForAlternativeConstrainBassinsDropDown
        function ChannelForAlternativeConstrainBassinsDropDownValueChanged(app, event)
            value = app.ChannelForAlternativeConstrainBassinsDropDown.Value;
            app.choice3=value;
            if strcmp(value,'Ch=1')
                app.im3=squeeze(app.im(:,:,1));
            elseif strcmp(value,'Ch=2')
                app.im3=squeeze(app.im(:,:,2));
            elseif strcmp(value,'Ch=3')
                app.im3=squeeze(app.im(:,:,3));
            elseif strcmp(value,'Ch=4')
                app.im3=squeeze(app.im(:,:,4));
            elseif strcmp(value,'max. int proj. all Channels')
                app.im3=squeeze(max(app.im,[],3));
            elseif strcmp(value,'<I(Ch)>')
                app.im3=squeeze(mean(app.im,3));
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.im3));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            if strcmp(value,'Gaussian')
                app.filtChoice='g';
            elseif strcmp(value,'LoG')
                app.filtChoice='log';
            elseif strcmp(value,'Disk')
                app.filtChoice='disk';
            elseif strcmp(value,'Local Average')
                app.filtChoice='average';
            end
            [app.filtAlternative,h2]=smoothSeries2(app.im3,app.filtSig,app.filtChoice,app.posNeg);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.filtAlternative));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changing function: FilterSigmaSlider
        function FilterSigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.filtSig=changingValue;
            [app.filtAlternative,h2]=smoothSeries2(app.im3,app.filtSig,app.filtChoice,app.posNeg);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.filtAlternative));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: FilterSigmaSlider
        function FilterSigmaSliderValueChanged(app, event)
            value = app.FilterSigmaSlider.Value;
            app.filtSig=value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value,'pos')
                app.posNeg='p';
            elseif strcmp(value,'neg')
                app.posNeg='n';
            end
            [app.filtAlternative,h2]=smoothSeries2(app.im3,app.filtSig,app.filtChoice,app.posNeg);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.filtAlternative));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Button pushed function: ApplyWatershedButton
        function ApplyWatershedButtonPushed(app, event)
            app.bwBdryFinal=ones(size(app.im1));
            app.bw3=app.bw2.*app.bwBdryFinal;
            app.bw3=bwmorph(app.bw3,'clean');
            app.bw3=bwmorph(app.bw3,'fill');
            % 3) combine info apply watershed
            norm2=app.filtAlternative/max(app.filtAlternative(:));
            mask_em=accumarray(round([app.out(:,2),app.out(:,1)]),ones(1,size(app.out,1)), [size(app.bw2,1),size(app.bw2,2)]);
            app.I_eq_c = imcomplement(norm2);
            app.I_mod = imimposemin(app.I_eq_c, ~app.bw3 | mask_em);
            app.Lb = watershed(app.I_mod,8);
            norm=app.im2/max(app.im2(:));
            B = labeloverlay(uint8(255*norm),app.Lb,'Transparency',0.75);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,B)
        end

        % Drop down opening function: DisplayResultsDropDown
        function DisplayResultsDropDownOpening(app, event)
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,app.I_eq_c)
        end

        % Value changed function: DisplayResultsDropDown
        function DisplayResultsDropDownValueChanged(app, event)
            value = app.DisplayResultsDropDown.Value;
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            if strcmp(value,'Inverse Average')
                imagesc(app.UIAxes,app.I_eq_c)
            elseif strcmp(value,'Inverse Average Constrained')
                imagesc(app.UIAxes,app.I_mod)
            elseif strcmp(value,'Watershed Results')
                rgb=label2rgb(app.Lb,'jet','k','shuffle');
                imagesc(app.UIAxes,rgb)
            end
        end

        % Value changed function: AreaUBEditField
        function AreaUBEditFieldValueChanged(app, event)
            value = app.AreaUBEditField.Value;
            app.areaUB=value;
        end

        % Value changed function: AreaLBEditField
        function AreaLBEditFieldValueChanged(app, event)
            value = app.AreaLBEditField.Value;
            app.areaLB=value;
        end

        % Button pushed function: FinalSegmentationButton
        function FinalSegmentationButtonPushed(app, event)
            Lb=app.Lb;
            firststat = regionprops(Lb);
            areas=[firststat.Area];
            ind1=find(areas<app.areaUB);
            ind2=find(areas>app.areaLB);
            indGood=intersect(ind1,ind2);
            Lb2=zeros(size(Lb));
            for i=1:length(indGood)
                ind=find(Lb==indGood(i));
                Lb2(ind)=i;
            end
            app.Lb=Lb2;
            stats=regionprops(app.Lb,'Centroid');
            xy=[stats.Centroid];

            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            norm=app.im2/max(app.im2(:));
            B = labeloverlay(uint8(255*norm),app.Lb,'Transparency',0.75);
            imagesc(app.UIAxes,B)
            hold(app.UIAxes,"on");
            for cel=1:size(stats,1)
                text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                hold(app.UIAxes,"on");
            end
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
            exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refImage.png'])

            %

            bw3=app.bw3;
            save([app.fname(1:end-4) 'masks.mat'],'bw3','Lb','-append')
        end

        % Button pushed function: 
        % Show2DVoronoiCellDecompositionFromNuclearLocationsButton
        function Show2DVoronoiCellDecompositionFromNuclearLocationsButtonPushed(app, event)
            [app.mask1,app.border] = voronoi2mask(app.out(:,1),app.out(:,2),size(app.im1));
            clear app.voronoi
            app.voronoi(:,:,3)=imadjust(normalisation(app.im1));
            app.voronoi(:,:,1)=imadjust(normalisation(app.border));
            app.voronoi(:,:,2)=nan(size(app.border));
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.voronoi));
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])

        end

        % Value changed function: ofpointsEditField
        function ofpointsEditFieldValueChanged(app, event)
            value = app.ofpointsEditField.Value;
            app.numPtsInt=round(value);
        end

        % Button pushed function: SelectPointsofInterestButton
        function SelectPointsofInterestButtonPushed(app, event)
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            norm=app.im2/max(app.im2(:));
            imagesc(app.UIAxes,norm)
            app.ptsint=[];
            title(app.UIAxes,'Please select point of interest')
            for i=1:app.numPtsInt;
                roi = drawpoint(app.UIAxes);
                xc = roi.Position(1);
                yc = roi.Position(2);
                app.ptsint(i,1)=xc;
                app.ptsint(i,2)=yc;
            end

        end

        % Value changed function: SmoothBoundaryDropDown
        function SmoothBoundaryDropDownValueChanged(app, event)
            value = app.SmoothBoundaryDropDown.Value;
            if strcmp(value,'Raw Boundary')
                app.MovAveFilt='raw';
            elseif strcmp(value,'Moving Averaged')
                app.MovAveFilt='movmean';
            elseif strcmp(value,'Moving Median')
                app.MovAveFilt='movmedian';
            elseif strcmp(value,'Gaussian')
                app.MovAveFilt='gaussian';
            elseif strcmp(value,'Lowess')
                app.MovAveFilt='lowess';
            elseif strcmp(value,'Loess')
                app.MovAveFilt='loess';
            elseif strcmp(value,'Robust Lowess')
                app.MovAveFilt='rlowess';
            elseif strcmp(value,'Robust Loess')
                app.MovAveFilt='rloess';
            elseif strcmp(value,'Savitzky-Golay')
                app.MovAveFilt='sgolay';
            end
        end

        % Value changing function: sigmaSlider
        function sigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            clear X Y
            app.bw2b=zeros(size(app.bw2));
            [boundaries,L,n] = bwboundaries(app.bw2,8);
            for i=1:n
                X{i} = boundaries{i}(:, 2);
                Y{i} = boundaries{i}(:, 1);
            end
            if strcmp(app.MovAveFilt,'raw')
                X2=X;
                Y2=Y;
                app.bw2b=app.bw2;
            else
                clear X2 Y2
                for i=1:n
                    X2{i} = smoothdata(X{i},app.MovAveFilt,round(changingValue));
                    Y2{i} = smoothdata(Y{i},app.MovAveFilt,round(changingValue));
                    app.bw2b=app.bw2b+poly2mask(X2{i},Y2{i},size(app.bw2,1),size(app.bw2,2));
                end
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            % Plot
            image(app.UIAxes,app.bw2)
            hold(app.UIAxes,"on");
            for i=1:n
                plot(app.UIAxes,X{i},Y{i},'r')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,X2{i},Y2{i},'g')
                hold(app.UIAxes,"on");
            end
            xlim(app.UIAxes,[1 size(app.bw2,2)])
            ylim(app.UIAxes,[1 size(app.bw2,1)])
        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            value = app.sigmaSlider.Value;
            app.sigmaBdry=value;
        end

        % Value changed function: 
        % BoundaryPointsUsedForCurvatureSlider
        function BoundaryPointsUsedForCurvatureSliderValueChanged(app, event)
            value = app.BoundaryPointsUsedForCurvatureSlider.Value;
            app.boundaryPoint=round(value);
        end

        % Value changed function: BoundaryPointsUsedForTangentSlider
        function BoundaryPointsUsedForTangentSliderValueChanged(app, event)
            value = app.BoundaryPointsUsedForTangentSlider.Value;
            app.bp_tangent=round(value);
        end

        % Value changed function: CurvatureThresholdSlider
        function CurvatureThresholdSliderValueChanged(app, event)
            value = app.CurvatureThresholdSlider.Value;
            app.curvatureThresh=value;
        end

        % Value changed function: MinDistanceInterpSlider
        function MinDistanceInterpSliderValueChanged(app, event)
            value = app.MinDistanceInterpSlider.Value;
            app.interpdmin=value;
        end

        % Value changed function: Open0orClosed1bdryEditField
        function Open0orClosed1bdryEditFieldValueChanged(app, event)
            value = app.Open0orClosed1bdryEditField.Value;
            app.loopclose=value;
        end

        % Button pushed function: CalculateCurvatureButton
        function CalculateCurvatureButtonPushed(app, event)
            [L,n]=bwlabel(app.bw2b);
            j=0;
            app.curveX=[];
            app.curveY=[];
            app.curveThresh=[];
            app.curveRaw=[];
            for i=1:n
                bw=(L==i)>0;
                [shape_details, Icurv] = curvature(bw, app.boundaryPoint, app.curvatureThresh,app.bp_tangent,app.interpdmin,app.loopclose);
                app.curveX{i}=shape_details.XY(:,1);
                app.curveY{i}=shape_details.XY(:,2);
                app.curveRaw{i}=shape_details.uncutCurvature;
                app.curveThresh{i}=shape_details.curvature;
                X = app.curveX{i};
                Y = app.curveY{i};
                C = app.curveThresh{i};
                X1(1+j:j+length(X))=X;
                Y1(1+j:j+length(X))=Y;
                C1(1+j:j+length(X))=C;
                j=j+length(X);
            end
            % Plot curvature
            [bigmycolors]=ColorMapPtPattern(C1);
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            % Plot
            image(app.UIAxes,app.bw2b)
            hold(app.UIAxes,"on");
            k=0;
            for ii=1:n
                X=app.curveX{ii};
                Y = app.curveY{ii};
                cols=bigmycolors(1+k:k+length(X),:);
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Plotting Curvature...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                j=1;
                for i=1:length(X)-1
                    plot(app.UIAxes,[X(i) X(i+1)],[Y(i) Y(i+1)],'Color',cols(i,:),'LineWidth',3)
                    hold(app.UIAxes,"on");
                    if mod(i,30)==0
                        ph.XData = [0 j/((length(X)-1)/30)  j/((length(X)-1)/30) 0];
                        th.String = sprintf('Plotting Curvature...%.0f%%',round((j/((length(X)-1)/30))*100));
                        drawnow %update graphics
                        j=j+1;
                    elseif i==length(X)-1
                        ph.XData = [0 j/((length(X)-1)/30)  j/((length(X)-1)/30) 0];
                        th.String = sprintf('Plotting Curvature...%.0f%%',round(100));
                        drawnow %update graphics
                    end
                end
                k=k+length(X);
            end
            hold(app.UIAxes,"off");
            xlim(app.UIAxes,[1 size(app.bw2,2)])
            ylim(app.UIAxes,[1 size(app.bw2,1)])
            cmap = jet;
            colormap(app.UIAxes,cmap);
            cb = colorbar(app.UIAxes);  % Add a colorbar
            cb.Label.String = 'Curvature';
            deltaC=max(C1)-min(C1);
            cb.TickLabels=round(min(C1):deltaC/10:max(C1),3);
            save([app.fname(1:end-4) 'masks.mat'],'X1','Y1','C1','-append')
        end

        % Value changing function: DefineOverallNucleiMaskSlider
        function DefineOverallNucleiMaskSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThreshNucleiMask=changingValue/10;
            curim=app.im1/max(app.im1(:));
            app.mask2=curim>app.ThreshNucleiMask;
            app.mask2=bwmorph(app.mask2,'clean');
            app.mask2=bwmorph(app.mask2,'close');
            app.mask2=bwmorph(app.mask2,'clean');
            app.mask2=imdilate(app.mask2,strel('disk',1));
            normim1=uint8(255*curim);
            B=labeloverlay(normim1,app.mask2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: DefineOverallNucleiMaskSlider
        function DefineOverallNucleiMaskSliderValueChanged(app, event)
            value = app.DefineOverallNucleiMaskSlider.Value;
            app.ThreshNucleiMask=value/10;
            curim=app.im1/max(app.im1(:));
            app.mask2=curim>app.ThreshNucleiMask;
            app.mask2=bwmorph(app.mask2,'clean');
            app.mask2=bwmorph(app.mask2,'close');
            app.mask2=bwmorph(app.mask2,'clean');
            app.mask2=imdilate(app.mask2,strel('disk',1));
            mask2=app.mask2;
            save([app.fname(1:end-4) 'masks.mat'],'mask2','-append')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.border=bwmorph(app.border,'thicken');
            SE = strel('square',3);
            app.border=imdilate(app.border,SE);
            app.maskcombine=(~app.border).*app.mask2;
            app.Lb2=bwlabel(app.maskcombine);
            curim=app.im1/max(app.im1(:));
            normim1=uint8(255*curim);
            B=labeloverlay(normim1,app.Lb2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: AreaUBEditField_2
        function AreaUBEditField_2ValueChanged(app, event)
            value = app.AreaUBEditField_2.Value;
            app.areaUB2=value;
        end

        % Value changed function: AreaLBEditField_2
        function AreaLBEditField_2ValueChanged(app, event)
            value = app.AreaLBEditField_2.Value;
            app.areaLB2=value;
        end

        % Button pushed function: Final2DVoronoiSegmentationButton
        function Final2DVoronoiSegmentationButtonPushed(app, event)
            Lb=app.Lb2;
            firststat = regionprops(Lb);
            areas=[firststat.Area];
            ind1=find(areas<app.areaUB2);
            ind2=find(areas>app.areaLB2);
            indGood=intersect(ind1,ind2);
            Lb2=zeros(size(Lb));
            for i=1:length(indGood)
                ind=find(Lb==indGood(i));
                Lb2(ind)=i;
            end
            app.Lb2=Lb2;
            stats=regionprops(app.Lb2,'Centroid');
            xy=[stats.Centroid];

            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            norm=app.im1/max(app.im1(:));
            B = labeloverlay(uint8(255*norm),app.Lb2,'Transparency',0.75);
            imagesc(app.UIAxes,B)
            hold(app.UIAxes,"on");
            for cel=1:size(stats,1)
                text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                hold(app.UIAxes,"on");
            end
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
            exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refVoronoiFinal.png'])
            Lb2=Lb;
            save([app.fname(1:end-4) 'masks.mat'],'Lb2','-append')
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)

            MinPeakDist=app.MinPeakDist;
            choice1=app.choice1;
            choice2=app.choice2;
            choice3=app.choice3;
            thresh1=app.thresh1;
            thresh2=app.thresh2;
            thresh3=app.thresh3;
            areaUB=app.areaUB;
            areaLB=app.areaLB;
            filtChoice=app.filtChoice;
            filtSig=app.filtSig;
            posNeg=app.posNeg;
            ThreshNucleiMask=app.ThreshNucleiMask;
            areaUB2=app.areaUB2;
            areaLB2=app.areaLB2;
            boundaryPoint=app.boundaryPoint;
            curvatureThresh=app.curvatureThresh;
            bp_tangent=app.bp_tangent;
            interpdmin=app.interpdmin;
            loopclose=app.loopclose;
            LBcellArea=app.LBcellArea;
            MovAveFilt=app.MovAveFilt;
            sigmaBdry=app.sigmaBdry;
            save([app.fname(1:end-4) '_ParameterFile.mat'],'sigmaBdry','MinPeakDist','choice1','choice2','choice3','thresh1','thresh2','thresh3','areaUB','areaLB','filtChoice','filtSig','posNeg','ThreshNucleiMask','areaUB2','areaLB2','boundaryPoint','curvatureThresh','bp_tangent','interpdmin','loopclose','LBcellArea','MovAveFilt');
        end

        % Button pushed function: LoadParamsButton
        function LoadParamsButtonPushed(app, event)
            filterspec = {'*.mat','Select Parameter file to load'};
            [f, p] = uigetfile(filterspec);
            load([p f]);
            app.MinimumPeakDistanceEditField.Value=MinPeakDist;
            app.SelectChannelToDefineCellNucleiCentersDropDown.Value=choice1;
            app.ImageforoverallcellshapebinaryDropDown.Value=choice2;
            app.ChannelForAlternativeConstrainBassinsDropDown.Value=choice3;
            app.DefineNucleiSmoothingRadiusSlider.Value=thresh1;
            app.CellCentersDetectionSlider.Value=10*thresh2;
            app.BinariseImage2Slider.Value=10*thresh3;
            app.AreaUBEditField.Value=areaUB;
            app.AreaLBEditField.Value=areaLB;
            if strcmp(filtChoice,'g')
                choice='Gaussian';
            elseif strcmp(filtChoice,'log')
                choice='LoG';
            elseif strcmp(filtChoice,'disk')
                choice='Disk';
            elseif strcmp(filtChoice,'average')
                choice='Local Average';
            end
            app.FilterTypeDropDown.Value=choice;
            app.FilterSigmaSlider.Value=filtSig;
            if strcmp(posNeg,'p')
                choice='pos';
            elseif strcmp(posNeg,'n')
                choice='neg';
            end
            app.DropDown.Value=choice;
            app.DefineOverallNucleiMaskSlider.Value=10*ThreshNucleiMask;
            app.AreaUBEditField_2.Value=areaUB2;
            app.AreaLBEditField_2.Value=areaLB2;
            app.BoundaryPointsUsedForCurvatureSlider.Value=boundaryPoint;
            app.CurvatureThresholdSlider.Value=curvatureThresh;
            app.BoundaryPointsUsedForTangentSlider.Value=bp_tangent;
            app.MinDistanceInterpSlider.Value=interpdmin;
            app.Open0orClosed1bdryEditField.Value=loopclose;
            app.LBCellAreaSlider.Value=LBcellArea;
            app.sigmaSlider.Value=sigmaBdry;
            if strcmp(MovAveFilt,'raw')
                value='Raw Boundary';
            elseif strcmp(MovAveFilt,'movmean')
                value='Moving Averaged';
            elseif strcmp(MovAveFilt,'movmedian')
                value='Moving Median';
            elseif strcmp(MovAveFilt,'gaussian')
                value='Gaussian';
            elseif strcmp(MovAveFilt,'lowess')
                value='Lowess';
            elseif strcmp(MovAveFilt,'loess')
                value='Loess';
            elseif strcmp(MovAveFilt,'rlowess')
                value='Robust Lowess';
            elseif strcmp(MovAveFilt,'rloess')
                value='Robust Loess';
            elseif strcmp(MovAveFilt,'sgolay')
                value='Savitzky-Golay';
            end
            app.SmoothBoundaryDropDown.Value=value;
        end

        % Button pushed function: StatsFromWatershedButton
        function StatsFromWatershedButtonPushed(app, event)
            %cla(app.UIAxes);
            %% extract props per cell.
            stats1=regionprops(app.Lb,'Centroid','Area','Circularity','Eccentricity','Perimeter','Solidity');
            % extract intensities per pixel in each cell in each channel
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            clear aveInt medianInt stdInt totInt

            for ch=1:size(app.im,3)
                stats2=regionprops(app.Lb,app.im(:,:,ch),'PixelValues');
                for ii=1:size(stats1,1)
                    totInt(ii,ch)=nansum(stats2(ii).PixelValues);
                    aveInt(ii,ch)=nanmean(stats2(ii).PixelValues);
                    medianInt(ii,ch)=nanmedian(stats2(ii).PixelValues);
                    stdInt(ii,ch)=nanstd(double(stats2(ii).PixelValues));
                end
                ph.XData = [0 ch/size(app.im,3)  ch/size(app.im,3) 0];
                th.String = sprintf('%.0f%%',round(ch/size(app.im,3)*100));
                drawnow %update graphics
            end
            %% do the curvature stuff...
            [L,no]=bwlabel(app.bw2b);
            if ~isempty(app.curveX)
                meanCurvature=[];
                se=strel('disk',10);

                for i=1:max(app.Lb(:))
                    cur=app.Lb==i;
                    cur = imdilate(cur,se);
                    cc_index = 1;
                    boundaries = bwboundaries(cur,8);
                    xc = boundaries{cc_index}(:, 2);
                    yc = boundaries{cc_index}(:, 1);
                    curcurve=[];
                    for ii=1:no
                        X = app.curveX{ii};
                        Y = app.curveY{ii};
                        C = app.curveThresh{ii};
                        ind=find(inpolygon(X,Y,xc,yc));
                        if ~isempty(ind)
                            curcurve=union(curcurve,C(ind));
                        end
                    end
                    meanCurvature(i,1)=mean(curcurve);
                end
            else
                meanCurvature=zeros(length([stats1.Area]),1);
            end
            %% do calculation to pts of interest...
            if ~isempty(app.ptsint)
                cent=[stats1.Centroid];
                x=cent(1:2:end)';
                y=cent(2:2:end)';
                for i=1:size(app.ptsint,1)
                    [Dc,I]=pdist2([app.ptsint(i,1),app.ptsint(i,2)],[x,y],'euclidean','Smallest',1);
                    app.distToPtsInt(:,i)=Dc';
                end
            else
                app.distToPtsInt=zeros(length([stats1.Area]),1);
            end
            clear GroupAll names
            names{1}='Area';
            names{2}='Perimeter';
            names{3}='Circularity';
            names{4}='Eccentricity';
            names{5}='Solidity';
            names{6}='Curvature';
            names{7}='DistToPt1';
            GroupAll(:,1)=[stats1.Area];
            GroupAll(:,2)=[stats1.Perimeter];
            GroupAll(:,3)=[stats1.Circularity];
            GroupAll(:,4)=[stats1.Eccentricity];
            GroupAll(:,5)=[stats1.Solidity];
            GroupAll(:,6)=meanCurvature;
            GroupAll(:,7)=app.distToPtsInt(:,1);
            k=8;
            for ch=1:size(app.im,3)
                names{k}=['TotIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,8+(ch-1)*4)=totInt(:,ch);
                names{k}=['MeanIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,9+(ch-1)*4)=aveInt(:,ch);
                names{k}=['MedianIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,10+(ch-1)*4)=medianInt(:,ch);
                names{k}=['stdIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,11+(ch-1)*4)=stdInt(:,ch);
            end
            sizeG=size(GroupAll,2);
            for i=2:size(app.ptsint,1)
                names{k}=['DistToPt' num2str(i)];
                GroupAll(:,sizeG+(i-1))=app.distToPtsInt(:,i);
                k=k+1;
            end
            T=array2table(GroupAll,'VariableNames',string(names));
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Watershed Results');
            %%

        end

        % Button pushed function: StatsFromVoronoiButton
        function StatsFromVoronoiButtonPushed(app, event)
            %% extract props per cell.
            stats1=regionprops(app.Lb2,'Area','Circularity','Perimeter','Solidity','Centroid');
            xy=[stats1.Centroid];
            xc=mean(xy(1:2:length(xy)));
            yc=mean(xy(2:2:length(xy)));
            distToC=((xy(1:2:length(xy))-xc).^2+(xy(2:2:length(xy))-yc).^2).^0.5;
            % extract intensities per pixel in each cell in each channel
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            clear aveInt medianInt stdInt totInt

            for ch=1:size(app.im,3)
                stats2=regionprops(app.Lb2,app.im(:,:,ch),'PixelValues');
                for ii=1:size(stats1,1)
                    totInt(ii,ch)=nansum(stats2(ii).PixelValues);
                    aveInt(ii,ch)=nanmean(stats2(ii).PixelValues);
                    medianInt(ii,ch)=nanmedian(stats2(ii).PixelValues);
                    stdInt(ii,ch)=nanstd(double(stats2(ii).PixelValues));
                end
                ph.XData = [0 ch/size(app.im,3)  ch/size(app.im,3) 0];
                th.String = sprintf('%.0f%%',round(ch/size(app.im,3)*100));
                drawnow %update graphics
            end
            %%
            clear GroupAll names
            names{1}='Area';
            names{2}='DistToCenter';
            GroupAll(:,1)=[stats1.Area];
            GroupAll(:,2)=distToC;
            k=3;
            for ch=1:size(app.im,3)
                names{k}=['TotIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,3+(ch-1)*4)=totInt(:,ch);
                names{k}=['MeanIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,4+(ch-1)*4)=aveInt(:,ch);
                names{k}=['MedianIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,5+(ch-1)*4)=medianInt(:,ch);
                names{k}=['stdIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,6+(ch-1)*4)=stdInt(:,ch);
            end
            T=array2table(GroupAll,'VariableNames',string(names));
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Vornoi Results');
            %% in cell bout out of nuclei
            stats1=regionprops(app.Lb,'Centroid');
            stats2=regionprops(app.Lb2,'Centroid');
            cell=[stats1.Centroid];
            x1=cell(1:2:end)';
            y1=cell(2:2:end)';
            nuc=[stats2.Centroid];
            x2=nuc(1:2:end)';
            y2=nuc(2:2:end)';
            [Dc,I]=pdist2([x1,y1],[x2,y2],'euclidean','Smallest',1);

            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');

            clear aveInt medianInt stdInt totInt nucID cellID
            for i=1:max(app.Lb2(:))
                nucm=app.Lb2==i;
                nucID(i,1)=i;
                cellm=app.Lb==I(i);
                cellID(i,1)=I(i);
                if sum(sum((cellm+nucm)>1))>0
                    curmask=(cellm-nucm)>0;
                    ind=find(curmask);
                    for ch=1:size(app.im,3)
                        im=app.im(:,:,ch);
                        pix=im(ind);
                        if ~isempty(pix)
                            totInt(i,ch)=nansum(pix);
                            aveInt(i,ch)=nanmean(pix);
                            medianInt(i,ch)=nanmedian(pix);
                            stdInt(i,ch)=nanstd(pix);
                        end
                    end
                else
                    totInt(i,:)=NaN;
                    aveInt(i,:)=NaN;
                    medianInt(i,:)=NaN;
                    stdInt(i,:)=NaN;
                end
                ph.XData = [0 i/max(app.Lb2(:))  i/max(app.Lb2(:)) 0];
                th.String = sprintf('%.0f%%',round(i/max(app.Lb2(:))*100));
                drawnow %update graphics
            end

            %%
            clear GroupAll names
            names{1}='NucID';
            names{2}='CellID';
            GroupAll(:,1)=nucID;
            GroupAll(:,2)=cellID;
            k=3;
            for ch=1:size(app.im,3)
                names{k}=['TotIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,3+(ch-1)*4)=totInt(:,ch);
                names{k}=['MeanIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,4+(ch-1)*4)=aveInt(:,ch);
                names{k}=['MedianIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,5+(ch-1)*4)=medianInt(:,ch);
                names{k}=['stdIntensityCh' num2str(ch)];
                k=k+1;
                GroupAll(:,6+(ch-1)*4)=stdInt(:,ch);
            end
            T=array2table(GroupAll,'VariableNames',string(names));
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Outside Nucleus');
            % voronoi part
            %             cla(app.UIAxes)
            %             imagesc(app.UIAxes,(app.voronoi));
            %             app.UIAxes.YDir='reverse';
            %             xlabel(app.UIAxes,'')
            %             ylabel(app.UIAxes,'')
            %             xlim(app.UIAxes,[1 size(app.im1,2)])
            %             ylim(app.UIAxes,[1 size(app.im1,1)])
            %             clear aveInt medianInt stdInt totInt names GroupAll
            %             for i=1:max(unique(app.mask1))
            %                 ind=find(app.mask1==i);
            %                 [row,col]=find(app.mask1==i);
            %                 hold(app.UIAxes,"on");
            %                 text(app.UIAxes,mean(col),mean(row),num2str(i),'FontSize',15,'Color',[1 1 1])
            %                 %hold on
            %                 for j=1:size(app.im,3)
            %                     currim=app.im(:,:,j);
            %                     aveInt(i,j)=mean(currim(ind));
            %                     medianInt(i,j)=median(currim(ind));
            %                     stdInt(i,j)=std(currim(ind));
            %                     totInt(i,j)=sum(currim(ind));
            %                     %app.CellArea(i,j)=length(ind);
            %                 end
            %             end
            %             exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refVoronoi.png'])
            %             k=1;
            %             for ch=1:size(app.im,3)
            %                 names{k}=['TotIntensityCh' num2str(ch)];
            %                 k=k+1;
            %                 GroupAll(:,1+(ch-1)*4)=totInt(:,ch);
            %                 names{k}=['MeanIntensityCh' num2str(ch)];
            %                 k=k+1;
            %                 GroupAll(:,2+(ch-1)*4)=aveInt(:,ch);
            %                 names{k}=['MedianIntensityCh' num2str(ch)];
            %                 k=k+1;
            %                 GroupAll(:,3+(ch-1)*4)=medianInt(:,ch);
            %                 names{k}=['stdIntensityCh' num2str(ch)];
            %                 k=k+1;
            %                 GroupAll(:,4+(ch-1)*4)=stdInt(:,ch);
            %             end
            %             T=array2table(GroupAll,'VariableNames',string(names));
            %             writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Voronoi Results');
            %
        end

        % Button pushed function: BatchusingsamesettingsButton
        function BatchusingsamesettingsButtonPushed(app, event)
            % select files to process
            [files, p] = uigetfile('*.czi;*.nd2;*.oif;*.lif','Select all OME files to process','MultiSelect','on');
            % process files one by one
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for f=1:length(files)
                %load file
                fname = [p files{f}];
                app.fname=fname;
                [im,specs]=readOMEAlternative2(app.fname);
                app.im=double(im);
                imagesc(app.UIAxes,(app.im(:,:,1)));

                % 1) find cell centers using average image
                if strcmp(app.choice1,'Ch=1')
                    app.im1=squeeze(app.im(:,:,1));
                elseif strcmp(app.choice1,'Ch=2')
                    app.im1=squeeze(app.im(:,:,2));
                elseif strcmp(app.choice1,'Ch=3')
                    app.im1=squeeze(app.im(:,:,3));
                elseif strcmp(app.choice1,'Ch=4')
                    app.im1=squeeze(app.im(:,:,4));
                end
                [app.filtnuc,h2]=smoothSeries2(app.im1,app.thresh1,'g','p');
                norm1=app.filtnuc/max(app.filtnuc(:));
                app.out=pkfnd(norm1,app.thresh2,app.MinPeakDist);
                % 2) find binary for overall cell shapes
                if strcmp(app.choice2,'Ch=1')
                    app.im2=squeeze(app.im(:,:,1));
                elseif strcmp(app.choice2,'Ch=2')
                    app.im2=squeeze(app.im(:,:,2));
                elseif strcmp(app.choice2,'Ch=3')
                    app.im2=squeeze(app.im(:,:,3));
                elseif strcmp(app.choice2,'Ch=4')
                    app.im2=squeeze(app.im(:,:,4));
                elseif strcmp(app.choice2,'max. int proj. all Channels')
                    app.im2=squeeze(max(app.im,[],3));
                elseif strcmp(app.choice2,'<I(Ch)>')
                    app.im2=squeeze(mean(app.im,3));
                end
                norm2=app.im2/max(app.im2(:));
                bw2=norm2>app.thresh3;
                % filter binary
                cc = bwconncomp(bw2);
                stats = regionprops(cc, 'Area');
                idx = find([stats.Area] >((app.MinPeakDist)^2));
                app.bw2 = ismember(labelmatrix(cc), idx);
                app.bw2=bwmorph(app.bw2,'clean');
                app.bw2=bwmorph(app.bw2,'close');
                app.bw2=bwmorph(app.bw2,'clean');
                % 3) combine info apply watershed
                if strcmp(app.choice3,'Ch=1')
                    app.im3=squeeze(app.im(:,:,1));
                elseif strcmp(app.choice3,'Ch=2')
                    app.im3=squeeze(app.im(:,:,2));
                elseif strcmp(app.choice3,'Ch=3')
                    app.im3=squeeze(app.im(:,:,3));
                elseif strcmp(app.choice3,'Ch=4')
                    app.im3=squeeze(app.im(:,:,4));
                elseif strcmp(app.choice3,'max. int proj. all Channels')
                    app.im3=squeeze(max(app.im,[],3));
                elseif strcmp(app.choice3,'<I(Ch)>')
                    app.im3=squeeze(mean(app.im,3));
                end
                % do watershed
                [app.filtAlternative,h2]=smoothSeries2(app.im3,app.filtSig,app.filtChoice,app.posNeg);
                norm2=app.filtAlternative/max(app.filtAlternative(:));
                mask_em=accumarray(round([app.out(:,2),app.out(:,1)]),ones(1,size(app.out,1)), [size(app.bw2,1),size(app.bw2,2)]);
                app.I_eq_c = imcomplement(norm2);
                app.I_mod = imimposemin(app.I_eq_c, ~app.bw2 | mask_em);
                app.Lb = watershed(app.I_mod,8);
                Lb=app.Lb;
                firststat = regionprops(Lb);
                areas=[firststat.Area];
                ind1=find(areas<app.areaUB);
                ind2=find(areas>app.areaLB);
                indGood=intersect(ind1,ind2);
                Lb2=zeros(size(Lb));
                for i=1:length(indGood)
                    ind=find(Lb==indGood(i));
                    Lb2(ind)=i;
                end
                app.Lb=Lb2;
                % define intensities per cell and cell properties
                 clear aveInt medianInt stdInt totInt
                for ch=1:size(app.im,3)
                    stats1=regionprops(app.Lb,app.im(:,:,ch),'PixelValues','Area','Circularity','Perimeter','Solidity','Centroid','Eccentricity');
                    for ii=1:size(stats1,1)
                        totInt(ii,ch)=nansum(stats1(ii).PixelValues);
                        aveInt(ii,ch)=nanmean(stats1(ii).PixelValues);
                        medianInt(ii,ch)=nanmedian(stats1(ii).PixelValues);
                        stdInt(ii,ch)=nanstd(double(stats1(ii).PixelValues));
                    end
                end
                
                % curvature if applies
                if ~isempty(app.curveThresh)
                app.bw2b=zeros(size(app.bw2));
                [boundaries,L,n] = bwboundaries(app.bw2,8);
                clear X Y X2 Y2
                for i=1:n
                    X{i} = boundaries{i}(:, 2);
                    Y{i} = boundaries{i}(:, 1);
                end
                if strcmp(app.MovAveFilt,'raw')
                    X2=X;
                    Y2=Y;
                    app.bw2b=app.bw2;
                else
                    clear X2 Y2
                    for i=1:n
                        X2{i} = smoothdata(X{i},app.MovAveFilt,round(app.sigmaBdry));
                        Y2{i} = smoothdata(Y{i},app.MovAveFilt,round(app.sigmaBdry));
                        app.bw2b=app.bw2b+poly2mask(X2{i},Y2{i},size(app.bw2,1),size(app.bw2,2));
                    end
                end

                [L,n]=bwlabel(app.bw2b);
                j=0;
                app.curveX=[];
                app.curveY=[];
                app.curveThresh=[];
                app.curveRaw=[];
                clear X1 Y1 C1
                for i=1:n
                    bw=(L==i)>0;
                    [shape_details, Icurv] = curvature(bw, app.boundaryPoint, app.curvatureThresh,app.bp_tangent,app.interpdmin,app.loopclose);
                    app.curveX{i}=shape_details.XY(:,1);
                    app.curveY{i}=shape_details.XY(:,2);
                    app.curveRaw{i}=shape_details.uncutCurvature;
                    app.curveThresh{i}=shape_details.curvature;
                    X = app.curveX{i};
                    Y = app.curveY{i};
                    C = app.curveThresh{i};
                    X1(1+j:j+length(X))=X;
                    Y1(1+j:j+length(X))=Y;
                    C1(1+j:j+length(X))=C;
                    j=j+length(X);
                end

                %% do the curvature stuff...
                [L,no]=bwlabel(app.bw2b);
                if ~isempty(app.curveX)
                    meanCurvature=[];
                    se=strel('disk',10);

                    for i=1:max(app.Lb(:))
                        cur=app.Lb==i;
                        cur = imdilate(cur,se);
                        cc_index = 1;
                        boundaries = bwboundaries(cur,8);
                        xc = boundaries{cc_index}(:, 2);
                        yc = boundaries{cc_index}(:, 1);
                        curcurve=[];
                        for ii=1:no
                            X = app.curveX{ii};
                            Y = app.curveY{ii};
                            C = app.curveThresh{ii};
                            ind=find(inpolygon(X,Y,xc,yc));
                            if ~isempty(ind)
                                curcurve=union(curcurve,C(ind));
                            end
                        end
                        meanCurvature(i,1)=mean(curcurve);
                    end
                else
                    meanCurvature=zeros(length([stats1.Area]),1);
                end
                else
                    meanCurvature=zeros(length([stats1.Area]),1);
                end

                %% do calculation to pts of interest...
                  app.distToPtsInt=[];
                if ~isempty(app.ptsint)
                    cent=[stats1.Centroid];
                    x=cent(1:2:end)';
                    y=cent(2:2:end)';
                    for i=1:size(app.ptsint,1)
                        [Dc,I]=pdist2([app.ptsint(i,1),app.ptsint(i,2)],[x,y],'euclidean','Smallest',1);
                        app.distToPtsInt(:,i)=Dc';
                    end
                    % use mask from step 2 to define centre of cells
                else
                     sta=regionprops(app.bw2,'Centroid');
                     cent=[sta.Centroid];
                     xc=mean(cent(1:2:end)');
                     yc=mean(cent(2:2:end)');
                     cent=[stats1.Centroid];
                     x=cent(1:2:end)';
                     y=cent(2:2:end)';
                     [Dc,I]=pdist2([xc,yc],[x,y],'euclidean','Smallest',1);
                     app.distToPtsInt(:,1)=Dc';
                    %app.distToPtsInt=zeros(length([stats1.Area]),1);
                end

                %% extract props per cell from watershed decomp
                clear GroupAll names
                names{1}='Area';
                names{2}='Perimeter';
                names{3}='Circularity';
                names{4}='Eccentricity';
                names{5}='Solidity';
                names{6}='Curvature';
                names{7}='DistToPt1';
                GroupAll(:,1)=[stats1.Area];
                GroupAll(:,2)=[stats1.Perimeter];
                GroupAll(:,3)=[stats1.Circularity];
                GroupAll(:,4)=[stats1.Eccentricity];
                GroupAll(:,5)=[stats1.Solidity];
                GroupAll(:,6)=meanCurvature;
                GroupAll(:,7)=app.distToPtsInt(:,1);
                k=8;
                for ch=1:size(app.im,3)
                    names{k}=['TotIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,8+(ch-1)*4)=totInt(:,ch);
                    names{k}=['MeanIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,9+(ch-1)*4)=aveInt(:,ch);
                    names{k}=['MedianIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,10+(ch-1)*4)=medianInt(:,ch);
                    names{k}=['stdIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,11+(ch-1)*4)=stdInt(:,ch);
                end
                sizeG=size(GroupAll,2);
                for i=2:size(app.ptsint,1)
                    names{k}=['DistToPt' num2str(i)];
                    GroupAll(:,sizeG+(i-1))=app.distToPtsInt(:,i);
                    k=k+1;
                end
                T=array2table(GroupAll,'VariableNames',string(names));
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Watershed Results');
                %% in cell but out of nuclei %%% nuclei cell mask if defined...
                if ~isempty(app.ThreshNucleiMask)
                    curim=app.im1/max(app.im1(:));
                    app.mask2=curim>app.ThreshNucleiMask;
                    app.mask2=bwmorph(app.mask2,'clean');
                    app.mask2=bwmorph(app.mask2,'close');
                    app.mask2=bwmorph(app.mask2,'clean');
                    app.mask2=imdilate(app.mask2,strel('disk',1));
                    [app.mask1,app.border] = voronoi2mask(app.out(:,1),app.out(:,2),size(app.im1));
                    SE = strel('square',3);
                    app.border=imdilate(app.border,SE);
                    app.maskcombine=(~app.border).*app.mask2;
                    app.Lb2=bwlabel(app.maskcombine);
                    Lb=app.Lb2;
                    firststat = regionprops(Lb);
                    areas=[firststat.Area];
                    ind1=find(areas<app.areaUB2);
                    ind2=find(areas>app.areaLB2);
                    indGood=intersect(ind1,ind2);
                    Lb2=zeros(size(Lb));
                    for i=1:length(indGood)
                        ind=find(Lb==indGood(i));
                        Lb2(ind)=i;
                    end
                    app.Lb2=Lb2;
                    stats2=regionprops(app.Lb2,'Area','Circularity','Perimeter','Solidity','Centroid');
                    xy=[stats2.Centroid];
                    xc=mean(xy(1:2:length(xy)));
                    yc=mean(xy(2:2:length(xy)));
                    distToC=((xy(1:2:length(xy))-xc).^2+(xy(2:2:length(xy))-yc).^2).^0.5;
                    % first Voronoi and threshold combo of nuclear areas
                    clear aveInt medianInt stdInt totInt 
                    for ch=1:size(app.im,3)
                        stats2=regionprops(app.Lb2,app.im(:,:,ch),'PixelValues','Area');
                        for ii=1:size(stats2,1)
                            totInt(ii,ch)=nansum(stats2(ii).PixelValues);
                            aveInt(ii,ch)=nanmean(stats2(ii).PixelValues);
                            medianInt(ii,ch)=nanmedian(stats2(ii).PixelValues);
                            stdInt(ii,ch)=nanstd(double(stats2(ii).PixelValues));
                        end
                    end
                    clear GroupAll names
                    names{1}='Area';
                    %stats2=regionprops(app.Lb2,'Area');
                    GroupAll(:,1)=[stats2.Area];
                    names{2}='DistToCenter';
                    GroupAll(:,2)=distToC;
                    k=3;
                    for ch=1:size(app.im,3)
                        names{k}=['TotIntensityCh' num2str(ch)];
                        k=k+1;
                        GroupAll(:,3+(ch-1)*4)=totInt(:,ch);
                        names{k}=['MeanIntensityCh' num2str(ch)];
                        k=k+1;
                        GroupAll(:,4+(ch-1)*4)=aveInt(:,ch);
                        names{k}=['MedianIntensityCh' num2str(ch)];
                        k=k+1;
                        GroupAll(:,5+(ch-1)*4)=medianInt(:,ch);
                        names{k}=['stdIntensityCh' num2str(ch)];
                        k=k+1;
                        GroupAll(:,6+(ch-1)*4)=stdInt(:,ch);
                    end
                T=array2table(GroupAll,'VariableNames',string(names));
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Vornoi Results');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % now outside nuclear area
                stats2=regionprops(app.Lb2,'Centroid');
                stats1=regionprops(app.Lb,'Centroid');
                cell=[stats1.Centroid];
                x1=cell(1:2:end)';
                y1=cell(2:2:end)';
                nuc=[stats2.Centroid];
                x2=nuc(1:2:end)';
                y2=nuc(2:2:end)';
                [Dc,I]=pdist2([x1,y1],[x2,y2],'euclidean','Smallest',1);
                clear aveInt medianInt stdInt totInt nucID cellID
                for i=1:max(app.Lb2(:))
                    nucm=app.Lb2==i;
                    nucID(i,1)=i;
                    cellm=app.Lb==I(i);
                    cellID(i,1)=I(i);
                    if sum(sum((cellm+nucm)>1))>0
                        curmask=(cellm-nucm)>0;
                        ind=find(curmask);
                        if ~isempty(ind)
                        for ch=1:size(app.im,3)
                            im=app.im(:,:,ch);
                            pix=im(ind);
                            totInt(i,ch)=nansum(pix);
                            aveInt(i,ch)=nanmean(pix);
                            medianInt(i,ch)=nanmedian(pix);
                            stdInt(i,ch)=nanstd(pix);
                        end
                        else
                            totInt(i,ch)=NaN;
                            aveInt(i,ch)=NaN;
                            medianInt(i,ch)=NaN;
                            stdInt(i,ch)=NaN;
                        end
                    else
                        totInt(i,:)=NaN;
                        aveInt(i,:)=NaN;
                        medianInt(i,:)=NaN;
                        stdInt(i,:)=NaN;
                    end

                end

                %%
                clear GroupAll names
                names{1}='NucID';
                names{2}='CellID';
                GroupAll(:,1)=nucID;
                GroupAll(:,2)=cellID;
                k=3;
                for ch=1:size(app.im,3)
                    names{k}=['TotIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,3+(ch-1)*4)=totInt(:,ch);
                    names{k}=['MeanIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,4+(ch-1)*4)=aveInt(:,ch);
                    names{k}=['MedianIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,5+(ch-1)*4)=medianInt(:,ch);
                    names{k}=['stdIntensityCh' num2str(ch)];
                    k=k+1;
                    GroupAll(:,6+(ch-1)*4)=stdInt(:,ch);
                end
                T=array2table(GroupAll,'VariableNames',string(names));
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Outside Nucleus');
                end % to if statement if defining the voronoi based segmentation

                %% export images
                stats=regionprops(app.Lb,'Centroid');
                xy=[stats.Centroid];
                norm=app.im2/max(app.im2(:));
                B = labeloverlay(uint8(255*norm),app.Lb,'Transparency',0.75);
                cla(app.UIAxes);
                imagesc(app.UIAxes,B)
                hold(app.UIAxes,"on");
                for cel=1:size(stats,1)
                    text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                    hold(app.UIAxes,"on");
                end
                xlim(app.UIAxes,[1 size(app.im1,2)])
                ylim(app.UIAxes,[1 size(app.im1,1)])
                exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refImage.png'])
                 
                 if ~isempty(app.ThreshNucleiMask)
                stats=regionprops(app.Lb2,'Centroid');
                xy=[stats.Centroid];
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                norm=app.im1/max(app.im1(:));
                B = labeloverlay(uint8(255*norm),app.Lb2,'Transparency',0.75);
                imagesc(app.UIAxes,B)
                hold(app.UIAxes,"on");
                for cel=1:size(stats,1)
                    text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                    hold(app.UIAxes,"on");
                end
                xlim(app.UIAxes,[1 size(app.im1,2)])
                ylim(app.UIAxes,[1 size(app.im1,1)])
                exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refVoronoiFinal.png'])   
                 end
                 
                Lb2=app.Lb2;
                Lb=app.Lb;
                save([app.fname(1:end-4) 'masks.mat'],'Lb2','Lb')
                
                ph.XData = [0 f/length(files)  f/length(files) 0];
                th.String = sprintf('%.0f%%',round(f/length(files)*100));
                drawnow %update graphics
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.LoadOMEImageDataButton = uibutton(app.UIFigure, 'push');
            app.LoadOMEImageDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOMEImageDataButtonPushed, true);
            app.LoadOMEImageDataButton.Text = 'Load OME Image Data';
            app.DefineTheCellCenterPositionsPanel = uipanel(app.UIFigure);
            app.DefineTheCellCenterPositionsPanel.Title = 'Define The Cell Center Positions';
            app.CellCentersDetectionSliderLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.CellCentersDetectionSliderLabel.Text = 'Cell Centers Detection';
            app.CellCentersDetectionSlider = uislider(app.DefineTheCellCenterPositionsPanel);
            app.CellCentersDetectionSlider.Limits = [0 10];
            app.CellCentersDetectionSlider.ValueChangedFcn = createCallbackFcn(app, @CellCentersDetectionSliderValueChanged, true);
            app.CellCentersDetectionSlider.ValueChangingFcn = createCallbackFcn(app, @CellCentersDetectionSliderValueChanging, true);
            app.SelectChannelToDefineCellNucleiCentersDropDownLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.SelectChannelToDefineCellNucleiCentersDropDownLabel.Text = 'Select Channel To Define Cell (Nuclei) Centers';
            app.SelectChannelToDefineCellNucleiCentersDropDown = uidropdown(app.DefineTheCellCenterPositionsPanel);
            app.SelectChannelToDefineCellNucleiCentersDropDown.Items = {'Ch=1', 'Ch=2', 'Ch=3', 'Ch=4'};
            app.SelectChannelToDefineCellNucleiCentersDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectChannelToDefineCellNucleiCentersDropDownValueChanged, true);
            app.SelectChannelToDefineCellNucleiCentersDropDown.Value = 'Ch=1';
            app.DefineNucleiSmoothingRadiusSliderLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.DefineNucleiSmoothingRadiusSliderLabel.Text = 'Define Nuclei Smoothing Radius';
            app.DefineNucleiSmoothingRadiusSlider = uislider(app.DefineTheCellCenterPositionsPanel);
            app.DefineNucleiSmoothingRadiusSlider.Limits = [1 100];
            app.DefineNucleiSmoothingRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @DefineNucleiSmoothingRadiusSliderValueChanged, true);
            app.DefineNucleiSmoothingRadiusSlider.ValueChangingFcn = createCallbackFcn(app, @DefineNucleiSmoothingRadiusSliderValueChanging, true);
            app.DefineNucleiSmoothingRadiusSlider.Value = 10;
            app.MinimumPeakDistanceEditFieldLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.MinimumPeakDistanceEditFieldLabel.Text = 'Minimum Peak Distance';
            app.MinimumPeakDistanceEditField = uieditfield(app.DefineTheCellCenterPositionsPanel, 'numeric');
            app.MinimumPeakDistanceEditField.Limits = [0 Inf];
            app.MinimumPeakDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPeakDistanceEditFieldValueChanged, true);
            app.MinimumPeakDistanceEditField.Value = 30;
            app.DefineOverallCellMaskPanel = uipanel(app.UIFigure);
            app.DefineOverallCellMaskPanel.Title = 'Define Overall Cell Mask';
            app.BinariseImage2SliderLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.BinariseImage2SliderLabel.Text = 'Binarise Image 2';
            app.BinariseImage2Slider = uislider(app.DefineOverallCellMaskPanel);
            app.BinariseImage2Slider.Limits = [0 10];
            app.BinariseImage2Slider.ValueChangedFcn = createCallbackFcn(app, @BinariseImage2SliderValueChanged, true);
            app.BinariseImage2Slider.ValueChangingFcn = createCallbackFcn(app, @BinariseImage2SliderValueChanging, true);
            app.ImageforoverallcellshapebinaryDropDownLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapebinaryDropDownLabel.Text = ' Image for overall cell shape binary';
            app.ImageforoverallcellshapebinaryDropDown = uidropdown(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapebinaryDropDown.Items = {'Ch=1', 'Ch=2', 'Ch=3', 'Ch=4', 'max. int proj. all Channels', '<I(Ch)>'};
            app.ImageforoverallcellshapebinaryDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageforoverallcellshapebinaryDropDownValueChanged, true);
            app.ImageforoverallcellshapebinaryDropDown.Value = 'Ch=1';
            app.GenerateOverallCellsMaskButton = uibutton(app.DefineOverallCellMaskPanel, 'push');
            app.GenerateOverallCellsMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOverallCellsMaskButtonPushed, true);
            app.GenerateOverallCellsMaskButton.Text = 'Generate Overall Cells Mask';
            app.LBCellAreaSliderLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.LBCellAreaSliderLabel.Text = 'LB Cell Area';
            app.LBCellAreaSlider = uislider(app.DefineOverallCellMaskPanel);
            app.LBCellAreaSlider.Limits = [0 1];
            app.LBCellAreaSlider.ValueChangedFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanged, true);
            app.LBCellAreaSlider.ValueChangingFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanging, true);
            app.LBCellAreaSlider.Value = 0.1;
            app.ApplyWatershedAndDefineCellAreasPanel = uipanel(app.UIFigure);
            app.ApplyWatershedAndDefineCellAreasPanel.Title = 'Apply Watershed And Define Cell Areas';
            app.ApplyWatershedButton = uibutton(app.ApplyWatershedAndDefineCellAreasPanel, 'push');
            app.ApplyWatershedButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyWatershedButtonPushed, true);
            app.ApplyWatershedButton.Text = 'Apply Watershed';
            app.DisplayResultsDropDownLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.DisplayResultsDropDownLabel.Text = 'Display Results';
            app.DisplayResultsDropDown = uidropdown(app.ApplyWatershedAndDefineCellAreasPanel);
            app.DisplayResultsDropDown.Items = {'Inverse Average', 'Inverse Average Constrained', 'Watershed Results'};
            app.DisplayResultsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DisplayResultsDropDownOpening, true);
            app.DisplayResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayResultsDropDownValueChanged, true);
            app.DisplayResultsDropDown.Value = 'Inverse Average';
            app.AreaUBEditFieldLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.AreaUBEditFieldLabel.Text = 'Area UB';
            app.AreaUBEditField = uieditfield(app.ApplyWatershedAndDefineCellAreasPanel, 'numeric');
            app.AreaUBEditField.Limits = [0 Inf];
            app.AreaUBEditField.ValueChangedFcn = createCallbackFcn(app, @AreaUBEditFieldValueChanged, true);
            app.AreaUBEditField.Value = 500000;
            app.AreaLBEditFieldLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.AreaLBEditFieldLabel.Text = 'Area LB';
            app.AreaLBEditField = uieditfield(app.ApplyWatershedAndDefineCellAreasPanel, 'numeric');
            app.AreaLBEditField.Limits = [0 Inf];
            app.AreaLBEditField.ValueChangedFcn = createCallbackFcn(app, @AreaLBEditFieldValueChanged, true);
            app.AreaLBEditField.Value = 50;
            app.ChannelForAlternativeConstrainBassinsDropDownLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.ChannelForAlternativeConstrainBassinsDropDownLabel.Text = 'Channel For Alternative Constrain Bassins';
            app.ChannelForAlternativeConstrainBassinsDropDown = uidropdown(app.ApplyWatershedAndDefineCellAreasPanel);
            app.ChannelForAlternativeConstrainBassinsDropDown.Items = {'Ch=1', 'Ch=2', 'Ch=3', 'Ch=4', 'max. int proj. all Channels', '<I(Ch)>'};
            app.ChannelForAlternativeConstrainBassinsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelForAlternativeConstrainBassinsDropDownValueChanged, true);
            app.ChannelForAlternativeConstrainBassinsDropDown.Value = 'Ch=1';
            app.FinalSegmentationButton = uibutton(app.ApplyWatershedAndDefineCellAreasPanel, 'push');
            app.FinalSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinalSegmentationButtonPushed, true);
            app.FinalSegmentationButton.Text = 'Final Segmentation ';
            app.FilterTypeDropDownLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.ApplyWatershedAndDefineCellAreasPanel);
            app.FilterTypeDropDown.Items = {'Gaussian', 'LoG', 'Disk', 'Local Average'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Gaussian';
            app.DropDown = uidropdown(app.ApplyWatershedAndDefineCellAreasPanel);
            app.DropDown.Items = {'pos', 'neg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'pos';
            app.FilterSigmaSliderLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.FilterSigmaSliderLabel.Text = 'Filter Sigma';
            app.FilterSigmaSlider = uislider(app.ApplyWatershedAndDefineCellAreasPanel);
            app.FilterSigmaSlider.Limits = [0.1 100];
            app.FilterSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @FilterSigmaSliderValueChanged, true);
            app.FilterSigmaSlider.ValueChangingFcn = createCallbackFcn(app, @FilterSigmaSliderValueChanging, true);
            app.FilterSigmaSlider.Value = 10;
            app.DVoronoiCombinedToNucleiMaskPanel = uipanel(app.UIFigure);
            app.DVoronoiCombinedToNucleiMaskPanel.Title = '2D Voronoi Combined To Nuclei Mask';
            app.DefineOverallNucleiMaskLabel = uilabel(app.DVoronoiCombinedToNucleiMaskPanel);
            app.DefineOverallNucleiMaskLabel.Text = ' Define Overall Nuclei Mask';
            app.DefineOverallNucleiMaskSlider = uislider(app.DVoronoiCombinedToNucleiMaskPanel);
            app.DefineOverallNucleiMaskSlider.Limits = [0 10];
            app.DefineOverallNucleiMaskSlider.ValueChangedFcn = createCallbackFcn(app, @DefineOverallNucleiMaskSliderValueChanged, true);
            app.DefineOverallNucleiMaskSlider.ValueChangingFcn = createCallbackFcn(app, @DefineOverallNucleiMaskSliderValueChanging, true);
            app.DefineOverallNucleiMaskSlider.Value = 1;
            app.Show2DVoronoiCellDecompositionFromNuclearLocationsButton = uibutton(app.DVoronoiCombinedToNucleiMaskPanel, 'push');
            app.Show2DVoronoiCellDecompositionFromNuclearLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @Show2DVoronoiCellDecompositionFromNuclearLocationsButtonPushed, true);
            app.Show2DVoronoiCellDecompositionFromNuclearLocationsButton.Text = 'Apply 2D Voronoi Decomposition From Nuclear Locations';
            app.Button = uibutton(app.DVoronoiCombinedToNucleiMaskPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Combine 2D Voronoi With Nuclei Mask to Extract Nuclei Morphology';
            app.AreaUBEditField_2Label = uilabel(app.DVoronoiCombinedToNucleiMaskPanel);
            app.AreaUBEditField_2Label.Text = 'Area UB';
            app.AreaUBEditField_2 = uieditfield(app.DVoronoiCombinedToNucleiMaskPanel, 'numeric');
            app.AreaUBEditField_2.Limits = [0 Inf];
            app.AreaUBEditField_2.ValueChangedFcn = createCallbackFcn(app, @AreaUBEditField_2ValueChanged, true);
            app.AreaUBEditField_2.Value = 10000;
            app.AreaLBEditField_2Label = uilabel(app.DVoronoiCombinedToNucleiMaskPanel);
            app.AreaLBEditField_2Label.Text = 'Area LB';
            app.AreaLBEditField_2 = uieditfield(app.DVoronoiCombinedToNucleiMaskPanel, 'numeric');
            app.AreaLBEditField_2.Limits = [0 Inf];
            app.AreaLBEditField_2.ValueChangedFcn = createCallbackFcn(app, @AreaLBEditField_2ValueChanged, true);
            app.AreaLBEditField_2.Value = 50;
            app.Final2DVoronoiSegmentationButton = uibutton(app.DVoronoiCombinedToNucleiMaskPanel, 'push');
            app.Final2DVoronoiSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @Final2DVoronoiSegmentationButtonPushed, true);
            app.Final2DVoronoiSegmentationButton.Text = 'Final 2D Voronoi Segmentation ';
            app.ComputeBoundaryCurvaturePanel = uipanel(app.UIFigure);
            app.ComputeBoundaryCurvaturePanel.Title = 'Compute Boundary Curvature';
            app.BoundaryPointsUsedForCurvatureSliderLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.BoundaryPointsUsedForCurvatureSliderLabel.Text = '# Boundary Points Used For Curvature';
            app.BoundaryPointsUsedForCurvatureSlider = uislider(app.ComputeBoundaryCurvaturePanel);
            app.BoundaryPointsUsedForCurvatureSlider.Limits = [3 50];
            app.BoundaryPointsUsedForCurvatureSlider.ValueChangedFcn = createCallbackFcn(app, @BoundaryPointsUsedForCurvatureSliderValueChanged, true);
            app.BoundaryPointsUsedForCurvatureSlider.Value = 10;
            app.BoundaryPointsUsedForTangentSliderLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.BoundaryPointsUsedForTangentSliderLabel.Text = '# Boundary Points Used For Tangent';
            app.BoundaryPointsUsedForTangentSlider = uislider(app.ComputeBoundaryCurvaturePanel);
            app.BoundaryPointsUsedForTangentSlider.Limits = [3 50];
            app.BoundaryPointsUsedForTangentSlider.ValueChangedFcn = createCallbackFcn(app, @BoundaryPointsUsedForTangentSliderValueChanged, true);
            app.BoundaryPointsUsedForTangentSlider.Value = 10;
            app.CurvatureThresholdSliderLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.CurvatureThresholdSliderLabel.Text = 'Curvature Threshold';
            app.CurvatureThresholdSlider = uislider(app.ComputeBoundaryCurvaturePanel);
            app.CurvatureThresholdSlider.Limits = [-1 1];
            app.CurvatureThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @CurvatureThresholdSliderValueChanged, true);
            app.CurvatureThresholdSlider.Value = 0.1;
            app.MinDistanceInterpSliderLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.MinDistanceInterpSliderLabel.Text = 'Min. Distance Interp.';
            app.MinDistanceInterpSlider = uislider(app.ComputeBoundaryCurvaturePanel);
            app.MinDistanceInterpSlider.Limits = [0.01 1];
            app.MinDistanceInterpSlider.ValueChangedFcn = createCallbackFcn(app, @MinDistanceInterpSliderValueChanged, true);
            app.MinDistanceInterpSlider.Value = 0.55;
            app.Open0orClosed1bdryEditFieldLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.Open0orClosed1bdryEditFieldLabel.Text = 'Open (0) or Closed (1) bdry';
            app.Open0orClosed1bdryEditField = uieditfield(app.ComputeBoundaryCurvaturePanel, 'numeric');
            app.Open0orClosed1bdryEditField.Limits = [0 1];
            app.Open0orClosed1bdryEditField.ValueChangedFcn = createCallbackFcn(app, @Open0orClosed1bdryEditFieldValueChanged, true);
            app.Open0orClosed1bdryEditField.Value = 1;
            app.CalculateCurvatureButton = uibutton(app.ComputeBoundaryCurvaturePanel, 'push');
            app.CalculateCurvatureButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCurvatureButtonPushed, true);
            app.CalculateCurvatureButton.Text = 'Calculate Curvature';
            app.SmoothBoundaryDropDownLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.SmoothBoundaryDropDownLabel.Text = 'Smooth Boundary:';
            app.SmoothBoundaryDropDown = uidropdown(app.ComputeBoundaryCurvaturePanel);
            app.SmoothBoundaryDropDown.Items = {'Raw Boundary', 'Moving Averaged', 'Gaussian', 'Lowess', 'Loess', 'Robust Lowess', 'Robust Loess', 'Savitzky-Golay'};
            app.SmoothBoundaryDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothBoundaryDropDownValueChanged, true);
            app.SmoothBoundaryDropDown.Value = 'Raw Boundary';
            app.sigmaSliderLabel = uilabel(app.ComputeBoundaryCurvaturePanel);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.ComputeBoundaryCurvaturePanel);
            app.sigmaSlider.Limits = [1 1000];
            app.sigmaSlider.MajorTicks = [0 200 400 600 800 1000];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.ValueChangingFcn = createCallbackFcn(app, @sigmaSliderValueChanging, true);
            app.sigmaSlider.Value = 1;
            app.ExtractStatsPerCellandBatchPanel = uipanel(app.UIFigure);
            app.ExtractStatsPerCellandBatchPanel.Title = 'Extract Stats Per Cell and Batch';
            app.BatchusingsamesettingsButton = uibutton(app.ExtractStatsPerCellandBatchPanel, 'push');
            app.BatchusingsamesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @BatchusingsamesettingsButtonPushed, true);
            app.BatchusingsamesettingsButton.Text = 'Batch using same settings';
            app.StatsFromWatershedButton = uibutton(app.ExtractStatsPerCellandBatchPanel, 'push');
            app.StatsFromWatershedButton.ButtonPushedFcn = createCallbackFcn(app, @StatsFromWatershedButtonPushed, true);
            app.StatsFromWatershedButton.Text = 'Stats From Watershed';
            app.StatsFromVoronoiButton = uibutton(app.ExtractStatsPerCellandBatchPanel, 'push');
            app.StatsFromVoronoiButton.ButtonPushedFcn = createCallbackFcn(app, @StatsFromVoronoiButtonPushed, true);
            app.StatsFromVoronoiButton.Text = 'Stats From Voronoi';
            app.SaveParamsButton = uibutton(app.ExtractStatsPerCellandBatchPanel, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.LoadParamsButton = uibutton(app.ExtractStatsPerCellandBatchPanel, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamsButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.DefinePointsofInterestPanel = uipanel(app.UIFigure);
            app.DefinePointsofInterestPanel.Title = 'Define Points of Interest';
            app.ofpointsEditFieldLabel = uilabel(app.DefinePointsofInterestPanel);
            app.ofpointsEditFieldLabel.Text = '# of points';
            app.ofpointsEditField = uieditfield(app.DefinePointsofInterestPanel, 'numeric');
            app.ofpointsEditField.ValueChangedFcn = createCallbackFcn(app, @ofpointsEditFieldValueChanged, true);
            app.ofpointsEditField.Value = 1;
            app.SelectPointsofInterestButton = uibutton(app.DefinePointsofInterestPanel, 'push');
            app.SelectPointsofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsofInterestButtonPushed, true);
            app.SelectPointsofInterestButton.Text = 'Select Points of Interest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ApplyWatershedAndVornoiSegmentCells2DWithCurvatureIslands

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end